classdef DSP < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure               matlab.ui.Figure
        Panel_5                matlab.ui.container.Panel
        UIAxes                 matlab.ui.control.UIAxes
        Panel_3                matlab.ui.container.Panel
        Label_8                matlab.ui.control.Label
        StartDest2             slrealtime.ui.control.StartStopButton
        ButtonPhase            matlab.ui.control.Button
        ButtonAmplitude        matlab.ui.control.Button
        ButtonTime             matlab.ui.control.Button
        Panel_2                matlab.ui.container.Panel
        EditFieldws1           matlab.ui.control.NumericEditField
        EditFieldwp1           matlab.ui.control.NumericEditField
        ButtonDesignAmplitude  matlab.ui.control.Button
        ButtonDesignPhase      matlab.ui.control.Button
        ButtonFilter           matlab.ui.control.Button
        EditFieldas            matlab.ui.control.NumericEditField
        Label_7                matlab.ui.control.Label
        EditFieldws            matlab.ui.control.NumericEditField
        Label_6                matlab.ui.control.Label
        EditFieldap            matlab.ui.control.NumericEditField
        Label_5                matlab.ui.control.Label
        EditFieldwp            matlab.ui.control.NumericEditField
        Label_4                matlab.ui.control.Label
        ButtonGroupChoose      matlab.ui.container.ButtonGroup
        Button_7               matlab.ui.control.RadioButton
        Button_6               matlab.ui.control.RadioButton
        Button_5               matlab.ui.control.RadioButton
        Button                 matlab.ui.control.RadioButton
        TabGroup2              matlab.ui.container.TabGroup
        FIRTab                 matlab.ui.container.Tab
        ButtonDesignFIR        matlab.ui.control.Button
        ButtonGroupWindow      matlab.ui.container.ButtonGroup
        ButtonKaiser           matlab.ui.control.RadioButton
        ButtonBlackman         matlab.ui.control.RadioButton
        ButtonHamming          matlab.ui.control.RadioButton
        ButtonHanning          matlab.ui.control.RadioButton
        ButtonBartlett         matlab.ui.control.RadioButton
        ButtonBoxcar           matlab.ui.control.RadioButton
        IIRTab                 matlab.ui.container.Tab
        ButtonGroupBase        matlab.ui.container.ButtonGroup
        ButtonButtord_2        matlab.ui.control.RadioButton
        ButtonEllipord         matlab.ui.control.RadioButton
        ButtonCheb1            matlab.ui.control.RadioButton
        ButtonCheb2            matlab.ui.control.RadioButton
        ButtonButtord          matlab.ui.control.RadioButton
        ButtonDesign           matlab.ui.control.Button
        Panel_4                matlab.ui.container.Panel
        Amplitude              matlab.ui.control.Button
        Phase                  matlab.ui.control.Button
        FFT                    matlab.ui.control.Button
        Panel                  matlab.ui.container.Panel
        TabGroup               matlab.ui.container.TabGroup
        Tab                    matlab.ui.container.Tab
        Action1                matlab.ui.control.Button
        SamplingPoints         matlab.ui.control.NumericEditField
        Label_2                matlab.ui.control.Label
        SamplingFrequency      matlab.ui.control.NumericEditField
        Label                  matlab.ui.control.Label
        Tab_2                  matlab.ui.container.Tab
        Label_3                matlab.ui.control.Label
        StartDest              slrealtime.ui.control.StartStopButton
        StartSource            slrealtime.ui.control.StartStopButton
        NoiseMulti             matlab.ui.control.CheckBox
        NoiseGaussian          matlab.ui.control.CheckBox
        NoiseSingle            matlab.ui.control.CheckBox
        NoiseWhite             matlab.ui.control.CheckBox
        Action2                matlab.ui.control.Button
    end

    
    properties (Access = private)
        y % 函数值
        t % 时间
        yfft % fft
        Fs % 采样频率
        Hk
        B % 系统函数分子
        A % 系统函数分母
        y2 % 滤波后函数值
        w1 
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: Action1
        function Action1ButtonPushed(app, event)
            N = app.SamplingPoints.Value;
            app.Fs = app.SamplingFrequency.Value;
            T = 1/app.Fs;
            app.t = 0:T:(N-1)*T;
            app.y = 100*sin(2*pi*5*app.t);
            for f = 10:5:40
                app.y = 100*sin(2*pi*f*app.t)+app.y; % 生成频率为5到40的多频正弦
            end
            stem(app.UIAxes,app.t,app.y,'.');
            title(app.UIAxes,'多频正弦信号');
        end

        % Button pushed function: Action2
        function Action2ButtonPushed(app, event)
            filename1 = 'voice.wav';
            filename2 = 'voice1.wav';
            [app.y,app.Fs] = audioread(filename1); % 读取音频
            T = 1/app.Fs;
            app.t = 0:T:(length(app.y)-1)*T;
            if app.NoiseWhite.Value
                app.y = app.y + 0.1*rand(length(app.y),1); % 白噪声
            end
            if app.NoiseSingle.Value
                app.y = app.y + 0.1*sin(20*pi*app.t)'; % 单频噪声
            end
            if app.NoiseGaussian.Value
                app.y = awgn(app.y,30); % 高斯噪声
            end
            if app.NoiseMulti.Value
                app.y = app.y + 0.05*sin(20*pi*app.t)' + 0.05*sin(2*20*pi*app.t)' + 0.1*sin(3*20*pi*app.t)'; % 多频噪声
            end
            audiowrite(filename2,app.y,app.Fs);
            stem(app.UIAxes,app.t,app.y,'.');
            title(app.UIAxes,'音频信号');
        end

        % Callback function
        function StartSourceButtonDown(app, event)
            [y1,app.Fs] = audioread("voice.wav");
            sound(y1,app.Fs); % 播放音频
        end

        % Callback function
        function StartDestButtonDown(app, event)
            [y1,app.Fs] = audioread("voice1.wav");
            sound(y1,app.Fs); % 播放处理后的音频
        end

        % Button pushed function: FFT
        function FFTButtonPushed(app, event)
            app.yfft = fft(app.y); % FFT变换
            stem(app.UIAxes,(0:length(app.yfft)-1)*app.Fs/length(app.yfft),abs(app.yfft),'.');
            title(app.UIAxes,'FFT幅频特性');
        end

        % Button pushed function: Phase
        function PhaseButtonPushed(app, event)
            stem(app.UIAxes,(0:length(app.yfft)-1)*app.Fs/length(app.yfft),angle(app.yfft)*180/pi,'.');
            title(app.UIAxes,'FFT相频特性');
        end

        % Button pushed function: Amplitude
        function AmplitudeButtonPushed(app, event)
            stem(app.UIAxes,(0:length(app.yfft)-1)*app.Fs/length(app.yfft),abs(app.yfft),'.');
            title(app.UIAxes,'FFT幅频特性');
        end

        % Button pushed function: ButtonDesign
        function ButtonDesignPushed(app, event)
            wp = app.EditFieldwp.Value;
            ws = app.EditFieldws.Value;
            ap = app.EditFieldap.Value;
            as = app.EditFieldas.Value;
            wp1 = app.EditFieldwp1.Value;
            ws1 = app.EditFieldws1.Value;
            ftype = app.ButtonGroupChoose.SelectedObject;
            switch ftype.Text
                case '低通'
                    ftype = 'low';
                case '高通'
                    ftype = 'high';
                case '带通'
                    ftype = 'bandpass';
                    wp = [wp,wp1];
                    ws = [ws,ws1];
                case '带阻'
                    ftype = 'stop';
                    wp = [wp,wp1];
                    ws = [ws,ws1];
            end

            base = app.ButtonGroupBase.SelectedObject;
            switch base.Text
                case '巴特沃斯'
                    [n,w]=buttord(wp*2/app.Fs,ws*2/app.Fs,ap,as);
                    [app.B,app.A]=butter(n,w,ftype);
                case '切比雪夫I'
                    [n,w]=cheb1ord(wp*2/app.Fs,ws*2/app.Fs,ap,as);
                    [app.B,app.A]=cheby1(n,ap,w,ftype);
                case '切比雪夫II'
                    [n,w]=cheb2ord(wp*2/app.Fs,ws*2/app.Fs,ap,as);
                    [app.B,app.A]=cheby2(n,as,w,ftype);
                case '椭圆'
                    [n,w]=ellipord(wp*2/app.Fs,ws*2/app.Fs,ap,as);
                    [app.B,app.A]=ellip(n,ap,as,w,ftype);
                case '巴特沃斯myself'
                    % 技术指标
                    wp2 =wp*2*pi;
                    ws2 =ws*2*pi;
                    % 阶数N1
                    N1 = ceil(log10((10^(0.1*as)-1) / (10^(0.1*ap)-1)) / (2*log10(ws2/wp2)));
                    % 截止频率
                    wc1 = wp2 / ((10^(0.1*ap)-1)^(1/(2*N1)));
                    wc2 = wp2 / ((10^(0.1*as)-1)^(1/(2*N1)));
                    % 极点
                    wc = wc1;
                    for k=1:N1
                        S(k) = wc * exp(i*pi*(1/2+(2*k-1)/(2*N1)));
                    end
                    syms s
                    % 系统函数分母
                    Hs = 1;
                    for k=1:length(S)
                        Hs = Hs * (s-S(k));
                    end
                    app.B = wc^N1;
                    app.A = real(sym2poly(Hs)); % 取系数的实部
                    [app.B,app.A] = bilinear(app.B,app.A,app.Fs); % 双线性变换法
            end
            
            [app.Hk,app.w1]=freqz(app.B,app.A);
            plot(app.UIAxes,app.w1*app.Fs/(2*pi),20*log10(abs(app.Hk)));
            title(app.UIAxes,'滤波器幅频特性');
        end

        % Button pushed function: ButtonDesignPhase
        function ButtonDesignPhasePushed(app, event)
            plot(app.UIAxes,app.w1*app.Fs/(2*pi),angle(app.Hk));
            title(app.UIAxes,'滤波器相频特性');
        end

        % Button pushed function: ButtonDesignAmplitude
        function ButtonDesignAmplitudePushed(app, event)
            plot(app.UIAxes,app.w1*app.Fs/(2*pi),20*log10(abs(app.Hk)));
            title(app.UIAxes,'滤波器幅频特性');
        end

        % Button pushed function: ButtonFilter
        function ButtonFilterPushed(app, event)
            app.y2 = filter(app.B,app.A,app.y); % 可换conv
            stem(app.UIAxes,app.t,app.y2,'.');
            title(app.UIAxes,'滤波后时域波形');
        end

        % Button pushed function: ButtonTime
        function ButtonTimePushed(app, event)
            stem(app.UIAxes,app.t,app.y2,'.');
            title(app.UIAxes,'滤波后时域波形');
        end

        % Button pushed function: ButtonAmplitude
        function ButtonAmplitudePushed(app, event)
            app.yfft = fft(app.y2);
            stem(app.UIAxes,(0:length(app.yfft)-1)*app.Fs/length(app.yfft),abs(app.yfft),'.');
            title(app.UIAxes,'滤波后幅频特性');
        end

        % Button pushed function: ButtonPhase
        function ButtonPhasePushed(app, event)
            stem(app.UIAxes,(0:length(app.yfft)-1)*app.Fs/length(app.yfft),angle(app.yfft)*180/pi,'.');
            title(app.UIAxes,'滤波后相频特性');
        end

        % Callback function
        function StartDest2ButtonDown(app, event)
            audiowrite("voice2.wav",app.y2,app.Fs); % 写入音频
            [y1,app.Fs] = audioread("voice2.wav");
            sound(y1,app.Fs);
        end

        % Button pushed function: ButtonDesignFIR
        function ButtonDesignFIRPushed(app, event)
            wp = app.EditFieldwp.Value*2/app.Fs;
            ws = app.EditFieldws.Value*2/app.Fs;
            wp1 = app.EditFieldwp1.Value*2/app.Fs;
            ws1 = app.EditFieldws1.Value*2/app.Fs;
            Bt = abs(wp-ws);
            wc = (wp+ws)/2;
            wc1 = (wp1+ws1)/2;
            ftype = app.ButtonGroupChoose.SelectedObject;
            switch ftype.Text
                case '低通'
                    ftype = 'low';
                case '高通'
                    ftype = 'high';
                case '带通'
                    ftype = 'bandpass';
                    wc = [wc,wc1];
                case '带阻'
                    ftype = 'stop';
                    wc = [wc,wc1];
            end

            base = app.ButtonGroupWindow.SelectedObject;
            switch base.Text
                case '矩形窗'
                    N = ceil(1.8/Bt);
                    N = N+mod(N+1,2);
                    hn = fir1(N-1,wc,ftype,boxcar(N));
                case '汉宁窗myself'
                    %N = ceil(6.2/Bt);
                    %N = N+mod(N+1,2);
                    %hn = fir1(N-1,wc,ftype,hanning(N));
                    ws = ws*pi;
                    wp = wp*pi;
                    wc = (wp+ws)/2;
                    Bt = ws-wp;
                    N1 = ceil(6.2*pi/Bt);
                    a = (N1-1)/2;
                    n = 1:N1+1;
                    wn = hanning(N1+1)';% 0.5*(1-cos(2*pi*n/N1))
                    hn = sin(wc*(n-a))./(pi*(n-a)).*wn;
                case '三角窗'
                    N = ceil(6.1/Bt);
                    N = N+mod(N+1,2);
                    hn = fir1(N-1,wc,ftype,bartlett(N));
                case '哈明窗'
                    N = ceil(6.6/Bt);
                    N = N+mod(N+1,2);
                    hn = fir1(N-1,wc,ftype,hamming(N));
                case '布莱克曼'
                    N = ceil(11/Bt);
                    N = N+mod(N+1,2);
                    hn = fir1(N-1,wc,ftype,blackman(N));
                case '凯塞窗'
                    N = ceil(10/Bt);
                    N = N+mod(N+1,2);
                    hn = fir1(N-1,wc,ftype,kaiser(N,7.865));
                    
            end
            app.B = hn;
            app.A = 1;
            [app.Hk,app.w1]=freqz(hn,1);
            plot(app.UIAxes,app.w1*app.Fs/(2*pi),20*log10(abs(app.Hk)));
            title(app.UIAxes,'滤波器幅频特性');
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.Panel = uipanel(app.UIFigure);
            app.Panel.Title = '生成信号';
            app.TabGroup = uitabgroup(app.Panel);
            app.Tab = uitab(app.TabGroup);
            app.Tab.Title = '多频正弦';
            app.Label = uilabel(app.Tab);
            app.Label.Text = '采样频率';
            app.SamplingFrequency = uieditfield(app.Tab, 'numeric');
            app.SamplingFrequency.Limits = [0 10000];
            app.SamplingFrequency.Value = 100;
            app.Label_2 = uilabel(app.Tab);
            app.Label_2.Text = '采样点数';
            app.SamplingPoints = uieditfield(app.Tab, 'numeric');
            app.SamplingPoints.Limits = [0 1000];
            app.SamplingPoints.Value = 256;
            app.Action1 = uibutton(app.Tab, 'push');
            app.Action1.ButtonPushedFcn = createCallbackFcn(app, @Action1ButtonPushed, true);
            app.Action1.Text = '生成';
            app.Tab_2 = uitab(app.TabGroup);
            app.Tab_2.Title = '数字音频';
            app.Action2 = uibutton(app.Tab_2, 'push');
            app.Action2.ButtonPushedFcn = createCallbackFcn(app, @Action2ButtonPushed, true);
            app.Action2.Text = '生成';
            app.NoiseWhite = uicheckbox(app.Tab_2);
            app.NoiseWhite.Text = '白噪声';
            app.NoiseSingle = uicheckbox(app.Tab_2);
            app.NoiseSingle.Text = '单频噪声';
            app.NoiseGaussian = uicheckbox(app.Tab_2);
            app.NoiseGaussian.Text = '高斯噪声';
            app.NoiseMulti = uicheckbox(app.Tab_2);
            app.NoiseMulti.Text = '多频噪声';
            app.StartSource = slrealtime.ui.control.StartStopButton(app.Tab_2);
            app.StartSource.ButtonDownFcn = createCallbackFcn(app, @StartSourceButtonDown, true);
            app.StartDest = slrealtime.ui.control.StartStopButton(app.Tab_2);
            app.StartDest.ButtonDownFcn = createCallbackFcn(app, @StartDestButtonDown, true);
            app.Label_3 = uilabel(app.Tab_2);
            app.Label_3.Text = '源文件    目标文件';
            app.Panel_4 = uipanel(app.UIFigure);
            app.Panel_4.Title = '频谱分析';
            app.FFT = uibutton(app.Panel_4, 'push');
            app.FFT.ButtonPushedFcn = createCallbackFcn(app, @FFTButtonPushed, true);
            app.FFT.Text = 'FFT';
            app.Phase = uibutton(app.Panel_4, 'push');
            app.Phase.ButtonPushedFcn = createCallbackFcn(app, @PhaseButtonPushed, true);
            app.Phase.Text = '相频';
            app.Amplitude = uibutton(app.Panel_4, 'push');
            app.Amplitude.ButtonPushedFcn = createCallbackFcn(app, @AmplitudeButtonPushed, true);
            app.Amplitude.Text = '幅频';
            app.Panel_2 = uipanel(app.UIFigure);
            app.Panel_2.Title = '数字滤波';
            app.TabGroup2 = uitabgroup(app.Panel_2);
            app.FIRTab = uitab(app.TabGroup2);
            app.FIRTab.Title = 'FIR';
            app.ButtonGroupWindow = uibuttongroup(app.FIRTab);
            app.ButtonGroupWindow.Title = '窗口';
            app.ButtonBoxcar = uiradiobutton(app.ButtonGroupWindow);
            app.ButtonBoxcar.Text = '矩形窗';
            app.ButtonBoxcar.Value = true;
            app.ButtonBartlett = uiradiobutton(app.ButtonGroupWindow);
            app.ButtonBartlett.Text = '三角窗';
            app.ButtonHanning = uiradiobutton(app.ButtonGroupWindow);
            app.ButtonHanning.Text = '汉宁窗myself';
            app.ButtonHamming = uiradiobutton(app.ButtonGroupWindow);
            app.ButtonHamming.Text = '哈明窗';
            app.ButtonBlackman = uiradiobutton(app.ButtonGroupWindow);
            app.ButtonBlackman.Text = '布莱克曼';
            app.ButtonKaiser = uiradiobutton(app.ButtonGroupWindow);
            app.ButtonKaiser.Text = '凯塞窗';
            app.ButtonDesignFIR = uibutton(app.FIRTab, 'push');
            app.ButtonDesignFIR.ButtonPushedFcn = createCallbackFcn(app, @ButtonDesignFIRPushed, true);
            app.ButtonDesignFIR.Text = '设计';
            app.IIRTab = uitab(app.TabGroup2);
            app.IIRTab.Title = 'IIR';
            app.ButtonDesign = uibutton(app.IIRTab, 'push');
            app.ButtonDesign.ButtonPushedFcn = createCallbackFcn(app, @ButtonDesignPushed, true);
            app.ButtonDesign.Text = '设计';
            app.ButtonGroupBase = uibuttongroup(app.IIRTab);
            app.ButtonGroupBase.Title = '基型';
            app.ButtonButtord = uiradiobutton(app.ButtonGroupBase);
            app.ButtonButtord.Text = '巴特沃斯';
            app.ButtonButtord.Value = true;
            app.ButtonCheb2 = uiradiobutton(app.ButtonGroupBase);
            app.ButtonCheb2.Text = '切比雪夫II';
            app.ButtonCheb1 = uiradiobutton(app.ButtonGroupBase);
            app.ButtonCheb1.Text = '切比雪夫I';
            app.ButtonEllipord = uiradiobutton(app.ButtonGroupBase);
            app.ButtonEllipord.Text = '椭圆';
            app.ButtonButtord_2 = uiradiobutton(app.ButtonGroupBase);
            app.ButtonButtord_2.Text = '巴特沃斯myself';
            app.ButtonGroupChoose = uibuttongroup(app.Panel_2);
            app.ButtonGroupChoose.Title = '选择';
            app.Button = uiradiobutton(app.ButtonGroupChoose);
            app.Button.Text = '低通';
            app.Button.Value = true;
            app.Button_5 = uiradiobutton(app.ButtonGroupChoose);
            app.Button_5.Text = '高通';
            app.Button_6 = uiradiobutton(app.ButtonGroupChoose);
            app.Button_6.Text = '带通';
            app.Button_7 = uiradiobutton(app.ButtonGroupChoose);
            app.Button_7.Text = '带阻';
            app.Label_4 = uilabel(app.Panel_2);
            app.Label_4.Text = '通带截止频率';
            app.EditFieldwp = uieditfield(app.Panel_2, 'numeric');
            app.EditFieldwp.Value = 12;
            app.Label_5 = uilabel(app.Panel_2);
            app.Label_5.Text = '通带最大衰减';
            app.EditFieldap = uieditfield(app.Panel_2, 'numeric');
            app.EditFieldap.Value = 1;
            app.Label_6 = uilabel(app.Panel_2);
            app.Label_6.Text = '阻带截止频率';
            app.EditFieldws = uieditfield(app.Panel_2, 'numeric');
            app.EditFieldws.Value = 16;
            app.Label_7 = uilabel(app.Panel_2);
            app.Label_7.Text = '阻带最小衰减';
            app.EditFieldas = uieditfield(app.Panel_2, 'numeric');
            app.EditFieldas.Value = 30;
            app.ButtonFilter = uibutton(app.Panel_2, 'push');
            app.ButtonFilter.ButtonPushedFcn = createCallbackFcn(app, @ButtonFilterPushed, true);
            app.ButtonFilter.Text = '滤波';
            app.ButtonDesignPhase = uibutton(app.Panel_2, 'push');
            app.ButtonDesignPhase.ButtonPushedFcn = createCallbackFcn(app, @ButtonDesignPhasePushed, true);
            app.ButtonDesignPhase.Text = '相频';
            app.ButtonDesignAmplitude = uibutton(app.Panel_2, 'push');
            app.ButtonDesignAmplitude.ButtonPushedFcn = createCallbackFcn(app, @ButtonDesignAmplitudePushed, true);
            app.ButtonDesignAmplitude.Text = '幅频';
            app.EditFieldwp1 = uieditfield(app.Panel_2, 'numeric');
            app.EditFieldws1 = uieditfield(app.Panel_2, 'numeric');
            app.Panel_3 = uipanel(app.UIFigure);
            app.Panel_3.Title = '输出分析';
            app.ButtonTime = uibutton(app.Panel_3, 'push');
            app.ButtonTime.ButtonPushedFcn = createCallbackFcn(app, @ButtonTimePushed, true);
            app.ButtonTime.Text = '时域';
            app.ButtonAmplitude = uibutton(app.Panel_3, 'push');
            app.ButtonAmplitude.ButtonPushedFcn = createCallbackFcn(app, @ButtonAmplitudePushed, true);
            app.ButtonAmplitude.Text = '幅频';
            app.ButtonPhase = uibutton(app.Panel_3, 'push');
            app.ButtonPhase.ButtonPushedFcn = createCallbackFcn(app, @ButtonPhasePushed, true);
            app.ButtonPhase.Text = '相频';
            app.StartDest2 = slrealtime.ui.control.StartStopButton(app.Panel_3);
            app.StartDest2.ButtonDownFcn = createCallbackFcn(app, @StartDest2ButtonDown, true);
            app.Label_8 = uilabel(app.Panel_3);
            app.Label_8.Text = '生成文件';
            app.Panel_5 = uipanel(app.UIFigure);
            app.Panel_5.Title = '信号';
            app.UIAxes = uiaxes(app.Panel_5);
            title(app.UIAxes, '信号波形')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            zlabel(app.UIAxes, 'Z')
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = DSP

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end